classdef AplicatieDeCriptareAMaesajelor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TipulcriptriiestedisponibildoarpentruMetodaSubstituieiLabel  matlab.ui.control.Label
        TextulcriptatLabel          matlab.ui.control.Label
        MetodutilizatDropDown       matlab.ui.control.DropDown
        MetodutilizatDropDownLabel  matlab.ui.control.Label
        TipulcriptriiButtonGroup    matlab.ui.container.ButtonGroup
        Monoalfabetic               matlab.ui.control.RadioButton
        Polialfabetic               matlab.ui.control.RadioButton
        AlgoritmiclasicideCriptareLabel  matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        ButonCriptare               matlab.ui.control.StateButton
        SelectCod                   matlab.ui.control.DropDown
        CodDropDownLabel            matlab.ui.control.Label
        InputCheie                  matlab.ui.control.EditField
        CheiaEditFieldLabel         matlab.ui.control.Label
        InputTextClar               matlab.ui.control.EditField
        TextclarEditFieldLabel      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SelectCod, SelectCod
        function SelectCodValueChanged(app, event)
            %value = app.SelectCod.Value;
       if app.MetodutilizatDropDown.Value == "Substituție"
            if app.Polialfabetic.Value
                % Set dropdown options for Polialfabetic (e.g., Vigenere)
                app.SelectCod.Items = {'Vigenere'};
            elseif app.Monoalfabetic.Value
                % Set dropdown options for Monoalfabetic (e.g., Caesar)
                app.SelectCod.Items = {'Cezar', 'Monoalfabetic'};
            end
       elseif app.MetodutilizatDropDown.Value == "Transpoziție"
           app.TipulcriptriiButtonGroup.Visible = true;
           app.Polialfabetic.Enable = false;
           app.Monoalfabetic.Enable = false;
           app.SelectCod.Items = {'Columnar'};

        % Șterge conținutul din casetele utilizatorului
        app.InputTextClar.Value = '';
        app.InputCheie.Value = '';
        app.TextArea.Value = '';

       end
        end

        % Value changed function: ButonCriptare
        function ButonCriptareValueChanged(app, event)
            % Preia valoarea din InputTextClar și InputCheie
textOriginal = app.InputTextClar.Value;
cheiaIntroducere = app.InputCheie.Value;
    

    if app.MetodutilizatDropDown.Value == "Substituție"
        % Verifică tipul de criptare
        if app.SelectCod.Value == "Cezar"
            % Criptare cu Cezar
            key = str2double(cheiaIntroducere);  
            textCriptat = caesarCipher(textOriginal, key);
            app.TextArea.Value = textCriptat;  % Afișează textul criptat în TextArea
        elseif app.SelectCod.Value == "Vigenere"
            % Criptare cu Vigenere
            key = upper(cheiaIntroducere);
            textCriptat = vigenereCipher(textOriginal, key);
            app.TextArea.Value = textCriptat;  % Afișează textul criptat în TextArea
        elseif app.SelectCod.Value == "Monoalfabetic"
            % Criptare cu Monoalfabetic
            key = upper(cheiaIntroducere);
            textCriptat = monoalfabeticCipher(textOriginal, key);
            app.TextArea.Value = textCriptat;  % Afișează textul criptat în TextArea
        end
    elseif app.MetodutilizatDropDown.Value == "Transpoziție"
        app.TipulcriptriiButtonGroup.Visible = true;
        app.Polialfabetic.Enable = false;
        app.Monoalfabetic.Enable = false;
    % Criptare folosind metoda transpoziției
       %key = upper(cheiaIntroducere);
       textCriptat = columnarCipher(textOriginal, cheiaIntroducere);
       app.TextArea.Value = textCriptat;  % Afișează textul criptat în TextArea
    end
        end

        % Drop down opening function: MetodutilizatDropDown
        function MetodutilizatDropDownOpening(app, event)
            % Preia valoarea selectată
        metodaSelectata = app.MetodutilizatDropDown.Value;

         if metodaSelectata == "Substituție"
            % Afișează caseta cu tipul criptării
            app.TipulcriptriiButtonGroup.Visible = true;
            app.Polialfabetic.Enable = true;
            app.Monoalfabetic.Enable = true;
            % Activează selecția pentru Monoalfabetic/Polialfabetic
            app.SelectCod.Items = {'Vigenere', 'Cezar', 'Monoalfabetic'};
            app.SelectCod.Value = 'Cezar'; % Setăm o valoare implicită
        elseif metodaSelectata == "Transpoziție"
            % Ascunde caseta cu tipul criptării
            app.TipulcriptriiButtonGroup.Visible = true;
            app.Polialfabetic.Enable = false;
            app.Monoalfabetic.Enable = false;
            % Actualizează opțiunile din SelectCod pentru transpoziție
            app.SelectCod.Items = {'Columnar'};
            app.SelectCod.Value = 'Columnar'; % Setăm o valoare implicită
         end

         % Șterge conținutul din casetele utilizatorului
        app.InputTextClar.Value = '';
        app.InputCheie.Value = '';
        app.TextArea.Value = '';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextclarEditFieldLabel = uilabel(app.UIFigure);
            app.TextclarEditFieldLabel.Text = 'Text clar:';
            app.InputTextClar = uieditfield(app.UIFigure, 'text');
            app.InputTextClar.Placeholder = 'Introduceți textul clar';
            app.CheiaEditFieldLabel = uilabel(app.UIFigure);
            app.CheiaEditFieldLabel.Text = 'Cheia:';
            app.InputCheie = uieditfield(app.UIFigure, 'text');
            app.InputCheie.Placeholder = 'Introduceți cheia de criptare';
            app.CodDropDownLabel = uilabel(app.UIFigure);
            app.CodDropDownLabel.Text = 'Cod:';
            app.SelectCod = uidropdown(app.UIFigure);
            app.SelectCod.Items = {'Vigenere', 'Cezar', 'Monoalfabetic'};
            app.SelectCod.DropDownOpeningFcn = createCallbackFcn(app, @SelectCodValueChanged, true);
            app.SelectCod.ValueChangedFcn = createCallbackFcn(app, @SelectCodValueChanged, true);
            app.SelectCod.Value = 'Vigenere';
            app.ButonCriptare = uibutton(app.UIFigure, 'state');
            app.ButonCriptare.ValueChangedFcn = createCallbackFcn(app, @ButonCriptareValueChanged, true);
            app.ButonCriptare.Text = 'Criptare';
            app.TextArea = uitextarea(app.UIFigure);
            app.AlgoritmiclasicideCriptareLabel = uilabel(app.UIFigure);
            app.AlgoritmiclasicideCriptareLabel.Text = 'Algoritmi clasici de Criptare';
            app.TipulcriptriiButtonGroup = uibuttongroup(app.UIFigure);
            app.TipulcriptriiButtonGroup.Title = 'Tipul criptării';
            app.Polialfabetic = uiradiobutton(app.TipulcriptriiButtonGroup);
            app.Polialfabetic.Text = 'Polialfabetic';
            app.Polialfabetic.Value = true;
            app.Monoalfabetic = uiradiobutton(app.TipulcriptriiButtonGroup);
            app.Monoalfabetic.Text = 'Monoalfabetic';
            app.MetodutilizatDropDownLabel = uilabel(app.UIFigure);
            app.MetodutilizatDropDownLabel.Text = 'Metodă utilizată:';
            app.MetodutilizatDropDown = uidropdown(app.UIFigure);
            app.MetodutilizatDropDown.Items = {'Substituție', 'Transpoziție'};
            app.MetodutilizatDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MetodutilizatDropDownOpening, true);
            app.MetodutilizatDropDown.Value = 'Substituție';
            app.TextulcriptatLabel = uilabel(app.UIFigure);
            app.TextulcriptatLabel.Text = 'Textul criptat';
            app.TipulcriptriiestedisponibildoarpentruMetodaSubstituieiLabel = uilabel(app.UIFigure);
            app.TipulcriptriiestedisponibildoarpentruMetodaSubstituieiLabel.Text = '!!!"Tipul criptării" este disponibil doar pentru Metoda Substituției';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AplicatieDeCriptareAMaesajelor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end